classdef Medical_Imaging_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DPhantomTab                     matlab.ui.container.Tab
        AcquisitionParametersPanel_2    matlab.ui.container.Panel
        BeamEnergykeVKnob_2             matlab.ui.control.DiscreteKnob
        BeamEnergykeVKnobLabel          matlab.ui.control.Label
        UpdateParametersButton_2        matlab.ui.control.Button
        SourcetoPhantomDistancecmSlider_2  matlab.ui.control.Slider
        SourcetoPhantomDistancecmSlider_2Label  matlab.ui.control.Label
        SourcetoFilmDistancecmSlider_2  matlab.ui.control.Slider
        SourcetoFilmDistancecmSlider_2Label  matlab.ui.control.Label
        XRayAngleDegreesSlider_2        matlab.ui.control.Slider
        XRayAngleDegreesSlider_2Label   matlab.ui.control.Label
        DPhantomTab_2                   matlab.ui.container.Tab
        AcquisitionParametersPanel      matlab.ui.container.Panel
        BeamEnergykeVKnob               matlab.ui.control.DiscreteKnob
        BeamEnergykeVKnob_3Label        matlab.ui.control.Label
        UpdateParametersButton          matlab.ui.control.Button
        SourcetoPhantomDistancecmSlider  matlab.ui.control.Slider
        SourcetoPhantomDistancecmSliderLabel  matlab.ui.control.Label
        SourcetoFilmDistancecmSlider    matlab.ui.control.Slider
        SourcetoFilmDistancecmLabel     matlab.ui.control.Label
        XRayAngleDegreesSlider          matlab.ui.control.Slider
        XRayAngleDegreesLabel           matlab.ui.control.Label
    end

    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XRayAngleDegreesSlider_2
        function XRayAngleDegreesSlider_2ValueChanged(app, event)
            app.XRayAngleDegreesSlider_2.Value = app.XRayAngleDegreesSlider_2.Value;
            
        end

        % Value changed function: SourcetoFilmDistancecmSlider_2
        function SourcetoFilmDistancecmSlider_2ValueChanged(app, event)
            app.SourcetoFilmDistancecmSlider_2.Value = app.SourcetoFilmDistancecmSlider_2.Value;
            
        end

        % Value changed function: SourcetoPhantomDistancecmSlider_2
        function SourcetoPhantomDistancecmSlider_2ValueChanged(app, event)
            app.SourcetoPhantomDistancecmSlider_2.Value = app.SourcetoPhantomDistancecmSlider_2.Value;
            
        end

        % Value changed function: BeamEnergykeVKnob_2
        function BeamEnergykeVKnob_2ValueChanged(app, event)
            app.BeamEnergykeVKnob_2.Value = app.BeamEnergykeVKnob_2.Value;
            
        end

        % Button pushed function: UpdateParametersButton_2
        function UpdateParametersButton_2Pushed(app, event)
            Phantom2DProject(app.BeamEnergykeVKnob_2.Value, ...
                app.XRayAngleDegreesSlider_2.Value, ...
                app.SourcetoFilmDistancecmSlider_2.Value, ...
                app.SourcetoPhantomDistancecmSlider_2.Value)
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            Phantom3DProject(app.BeamEnergykeVKnob.Value, ...
                app.XRayAngleDegreesSlider.Value, ...
                app.SourcetoPhantomDistancecmSlider.Value, ...
                app.SourcetoPhantomDistancecmSlider.Value)
        end

        % Value changed function: BeamEnergykeVKnob
        function BeamEnergykeVKnobValueChanged(app, event)
            app.BeamEnergykeVKnob.Value = app.BeamEnergykeVKnob.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DPhantomTab = uitab(app.TabGroup);
            app.DPhantomTab.Title = '2D-Phantom';
            app.AcquisitionParametersPanel_2 = uipanel(app.DPhantomTab);
            app.AcquisitionParametersPanel_2.Title = 'Acquisition Parameters';
            app.XRayAngleDegreesSlider_2Label = uilabel(app.AcquisitionParametersPanel_2);
            app.XRayAngleDegreesSlider_2Label.Text = 'X-Ray Angle (Degrees):';
            app.XRayAngleDegreesSlider_2 = uislider(app.AcquisitionParametersPanel_2);
            app.XRayAngleDegreesSlider_2.Limits = [40 60];
            app.XRayAngleDegreesSlider_2.ValueChangedFcn = createCallbackFcn(app, @XRayAngleDegreesSlider_2ValueChanged, true);
            app.XRayAngleDegreesSlider_2.Value = 40;
            app.SourcetoFilmDistancecmSlider_2Label = uilabel(app.AcquisitionParametersPanel_2);
            app.SourcetoFilmDistancecmSlider_2Label.Text = 'Source-to-Film Distance (cm):';
            app.SourcetoFilmDistancecmSlider_2 = uislider(app.AcquisitionParametersPanel_2);
            app.SourcetoFilmDistancecmSlider_2.Limits = [0 60];
            app.SourcetoFilmDistancecmSlider_2.ValueChangedFcn = createCallbackFcn(app, @SourcetoFilmDistancecmSlider_2ValueChanged, true);
            app.SourcetoPhantomDistancecmSlider_2Label = uilabel(app.AcquisitionParametersPanel_2);
            app.SourcetoPhantomDistancecmSlider_2Label.Text = 'Source-to-Phantom Distance (cm):';
            app.SourcetoPhantomDistancecmSlider_2 = uislider(app.AcquisitionParametersPanel_2);
            app.SourcetoPhantomDistancecmSlider_2.Limits = [0 60];
            app.SourcetoPhantomDistancecmSlider_2.ValueChangedFcn = createCallbackFcn(app, @SourcetoPhantomDistancecmSlider_2ValueChanged, true);
            app.UpdateParametersButton_2 = uibutton(app.AcquisitionParametersPanel_2, 'push');
            app.UpdateParametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButton_2Pushed, true);
            app.UpdateParametersButton_2.Text = 'Update Parameters';
            app.BeamEnergykeVKnobLabel = uilabel(app.AcquisitionParametersPanel_2);
            app.BeamEnergykeVKnobLabel.Text = 'Beam Energy (keV)';
            app.BeamEnergykeVKnob_2 = uiknob(app.AcquisitionParametersPanel_2, 'discrete');
            app.BeamEnergykeVKnob_2.Items = {'Low (15)', 'Medium (19)', 'High (26)'};
            app.BeamEnergykeVKnob_2.ItemsData = [15 19 26];
            app.BeamEnergykeVKnob_2.ValueChangedFcn = createCallbackFcn(app, @BeamEnergykeVKnob_2ValueChanged, true);
            app.BeamEnergykeVKnob_2.Value = 15;
            app.DPhantomTab_2 = uitab(app.TabGroup);
            app.DPhantomTab_2.Title = '3D-Phantom';
            app.AcquisitionParametersPanel = uipanel(app.DPhantomTab_2);
            app.AcquisitionParametersPanel.Title = 'Acquisition Parameters';
            app.XRayAngleDegreesLabel = uilabel(app.AcquisitionParametersPanel);
            app.XRayAngleDegreesLabel.Text = 'X-Ray Angle (Degrees):';
            app.XRayAngleDegreesSlider = uislider(app.AcquisitionParametersPanel);
            app.XRayAngleDegreesSlider.Limits = [40 60];
            app.XRayAngleDegreesSlider.Value = 40;
            app.SourcetoFilmDistancecmLabel = uilabel(app.AcquisitionParametersPanel);
            app.SourcetoFilmDistancecmLabel.Text = 'Source-to-Film Distance (cm):';
            app.SourcetoFilmDistancecmSlider = uislider(app.AcquisitionParametersPanel);
            app.SourcetoFilmDistancecmSlider.Limits = [0 60];
            app.SourcetoPhantomDistancecmSliderLabel = uilabel(app.AcquisitionParametersPanel);
            app.SourcetoPhantomDistancecmSliderLabel.Text = 'Source-to-Phantom Distance (cm):';
            app.SourcetoPhantomDistancecmSlider = uislider(app.AcquisitionParametersPanel);
            app.SourcetoPhantomDistancecmSlider.Limits = [0 60];
            app.UpdateParametersButton = uibutton(app.AcquisitionParametersPanel, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.BeamEnergykeVKnob_3Label = uilabel(app.AcquisitionParametersPanel);
            app.BeamEnergykeVKnob_3Label.Text = 'Beam Energy (keV)';
            app.BeamEnergykeVKnob = uiknob(app.AcquisitionParametersPanel, 'discrete');
            app.BeamEnergykeVKnob.Items = {'Low (15)', 'Medium (19)', 'High (26)'};
            app.BeamEnergykeVKnob.ItemsData = [15 19 26];
            app.BeamEnergykeVKnob.ValueChangedFcn = createCallbackFcn(app, @BeamEnergykeVKnobValueChanged, true);
            app.BeamEnergykeVKnob.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Medical_Imaging_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end